classdef MatlapProjesi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Switch_3                     matlab.ui.control.Switch
        Switch_3Label                matlab.ui.control.Label
        FiyatButtonGroup             matlab.ui.container.ButtonGroup
        Button_8                     matlab.ui.control.RadioButton
        Button_7                     matlab.ui.control.RadioButton
        Button_6                     matlab.ui.control.RadioButton
        BeyazesyayaidgrearamayapEditField  matlab.ui.control.EditField
        BeyazesyayaidgrearamayapEditFieldLabel  matlab.ui.control.Label
        Switch_2                     matlab.ui.control.ToggleSwitch
        Switch_2Label                matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        Lamp                         matlab.ui.control.Lamp
        Label_3                      matlab.ui.control.Label
        BeyazEyanzSeinizLabel        matlab.ui.control.Label
        Switch                       matlab.ui.control.ToggleSwitch
        Label_2                      matlab.ui.control.Label
        KriterleriSeinizLabel        matlab.ui.control.Label
        BeyazesyaSipariiLabel        matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        Label                        matlab.ui.control.Label
        EnerjiSnfButtonGroup         matlab.ui.container.ButtonGroup
        CButton                      matlab.ui.control.RadioButton
        AButton_2                    matlab.ui.control.RadioButton
        AButton                      matlab.ui.control.RadioButton
        EnerjiVerimliliiButtonGroup  matlab.ui.container.ButtonGroup
        kwhButton_3                  matlab.ui.control.RadioButton
        kwhButton_2                  matlab.ui.control.RadioButton
        kwhButton                    matlab.ui.control.RadioButton
        SesSeviyesiButtonGroup       matlab.ui.container.ButtonGroup
        DBButton_3                   matlab.ui.control.RadioButton
        DBButton_2                   matlab.ui.control.RadioButton
        DBButton                     matlab.ui.control.RadioButton
        GarantiSresiButtonGroup      matlab.ui.container.ButtonGroup
        Button_5                     matlab.ui.control.RadioButton
        Button_4                     matlab.ui.control.RadioButton
        Button                       matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Switch_4ValueChanged(app, event)
            value = app.Switch_4.Value;
            if(strcmp(app.Switch_4.Value,'Başlat'))
                for i=0:100
                    app.Gauge.Value=app.Gauge.Value+1
                    pause(1)
                end
                 app.Gauge.Value=app.Gauge.Value       
            else
                for i=0:100
                    app.Gauge.Value=app.Gauge.Value
                    pause(1)
                end
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if(strcmp(app.Switch.Value,'Siparişet'))
               garantisuresi=app.GarantiSresiButtonGroup.SelectedObject.Text
               enerjiverimlilik=app.EnerjiVerimliliiButtonGroup.SelectedObject.Text
               sesseviyesi=app.SesSeviyesiButtonGroup.SelectedObject.Text
               enerjisinif=app.EnerjiSnfButtonGroup.SelectedObject.Text
               fiyat=app.FiyatButtonGroup.SelectedObject.Text
               beyzesya=app.ListBox.Value
               datasource='hatice'
               username='sa'
               password='1'
               conn=odbc(datasource,username,password)
               query=['INSERT INTO matlap.dbo.matlap_proje (garantisüresi,enerjiverimlilik,sesseviyesi,enerjisınıfı,fiyat,beyaz_esya) values(''' garantisuresi ''',''' enerjiverimlilik ''',''' sesseviyesi ''',''' enerjisinif ''',''' fiyat ''',''' beyzesya ''' )']
               data=fetch(conn,query)
               app.UITable.Data=data
               execute(conn,query)
               close(conn)
               app.Lamp.Color="blue"
               msg = 'Sipariş verildi';
               msgbox(msg, 'Success', 'modal');
               pause(1)
               msg = 'Kargoya verildi';
               msgbox(msg, 'Success', 'modal');
               

            else
                 app.Lamp.Color="white"
            end
        end

        % Selection changed function: GarantiSresiButtonGroup
        function GarantiSresiButtonGroupSelectionChanged(app, event)
            selectedButton = app.GarantiSresiButtonGroup.SelectedObject;  
        end

        % Selection changed function: EnerjiVerimliliiButtonGroup
        function EnerjiVerimliliiButtonGroupSelectionChanged(app, event)
            selectedButton = app.EnerjiVerimliliiButtonGroup.SelectedObject;   
        end

        % Selection changed function: SesSeviyesiButtonGroup
        function SesSeviyesiButtonGroupSelectionChanged(app, event)
            selectedButton = app.SesSeviyesiButtonGroup.SelectedObject;  
        end

        % Selection changed function: EnerjiSnfButtonGroup
        function EnerjiSnfButtonGroupSelectionChanged(app, event)
            selectedButton = app.EnerjiSnfButtonGroup.SelectedObject;
        end

        % Selection changed function: FiyatButtonGroup
        function FiyatButtonGroupSelectionChanged(app, event)
            selectedButton = app.FiyatButtonGroup.SelectedObject;   
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if(strcmp(app.Switch_2.Value,'Siparişigörüntüle'))
            datasource="hatice"
            username='sa'
            password='1'
            conn=odbc(datasource ,username, password)
            query="select *from matlap.dbo.matlap_proje"
            data=fetch(conn,query)
            app.UITable.Data=data
            execute(conn,query)
            close(conn)
            else
            datasource = 'hatice';
            username = 'sa';
            password = '1';
            conn = odbc(datasource, username, password);
            deleteQuery = 'DELETE FROM matlap.dbo.matlap_proje';
            execute(conn, deleteQuery);
            close(conn);
            app.UITable.Data = []; 
            msg = 'Siparişler silindi';
            msgbox(msg, 'Success', 'modal');
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = app.Switch_3.Value; 
            if(strcmp(app.Switch_3.Value,'AramaYap'))
            datasource='hatice'
            username='sa'
            password='1'
            conn=odbc(datasource,username,password)
            id=app.BeyazesyayaidgrearamayapEditField.Value
            query=['SELECT  *FROM matlap.dbo.matlap_proje  where id = ''' id '''' ]
            data=fetch(conn,query)
            app.UITable.Data=data
            execute(conn,query)
            close(conn)
            msg = 'arama yapıldı ';
            msgbox(msg, 'Success', 'modal');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GarantiSresiButtonGroup = uibuttongroup(app.UIFigure);
            app.GarantiSresiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GarantiSresiButtonGroupSelectionChanged, true);
            app.GarantiSresiButtonGroup.Title = 'Garanti Süresi';
            app.Button = uiradiobutton(app.GarantiSresiButtonGroup);
            app.Button.Text = '36';
            app.Button.Value = true;
            app.Button_4 = uiradiobutton(app.GarantiSresiButtonGroup);
            app.Button_4.Text = '24';
            app.Button_5 = uiradiobutton(app.GarantiSresiButtonGroup);
            app.Button_5.Text = '12';
            app.SesSeviyesiButtonGroup = uibuttongroup(app.UIFigure);
            app.SesSeviyesiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SesSeviyesiButtonGroupSelectionChanged, true);
            app.SesSeviyesiButtonGroup.Title = 'Ses Seviyesi';
            app.DBButton = uiradiobutton(app.SesSeviyesiButtonGroup);
            app.DBButton.Text = '40(DB)';
            app.DBButton.Value = true;
            app.DBButton_2 = uiradiobutton(app.SesSeviyesiButtonGroup);
            app.DBButton_2.Text = '50(DB)';
            app.DBButton_3 = uiradiobutton(app.SesSeviyesiButtonGroup);
            app.DBButton_3.Text = '70(DB)';
            app.EnerjiVerimliliiButtonGroup = uibuttongroup(app.UIFigure);
            app.EnerjiVerimliliiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EnerjiVerimliliiButtonGroupSelectionChanged, true);
            app.EnerjiVerimliliiButtonGroup.Title = 'Enerji Verimliliği';
            app.kwhButton = uiradiobutton(app.EnerjiVerimliliiButtonGroup);
            app.kwhButton.Text = '250(kwh)';
            app.kwhButton.Value = true;
            app.kwhButton_2 = uiradiobutton(app.EnerjiVerimliliiButtonGroup);
            app.kwhButton_2.Text = '300(kwh)';
            app.kwhButton_3 = uiradiobutton(app.EnerjiVerimliliiButtonGroup);
            app.kwhButton_3.Text = '240(kwh)';
            app.EnerjiSnfButtonGroup = uibuttongroup(app.UIFigure);
            app.EnerjiSnfButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EnerjiSnfButtonGroupSelectionChanged, true);
            app.EnerjiSnfButtonGroup.Title = 'Enerji Sınıfı';
            app.AButton = uiradiobutton(app.EnerjiSnfButtonGroup);
            app.AButton.Text = 'A';
            app.AButton.Value = true;
            app.AButton_2 = uiradiobutton(app.EnerjiSnfButtonGroup);
            app.AButton_2.Text = 'A++';
            app.CButton = uiradiobutton(app.EnerjiSnfButtonGroup);
            app.CButton.Text = 'C';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Buzdolabı', 'Çamaşır Makinesi', 'Bulaşık Makinesi', 'Derin Dondurucu'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Buzdolabı';
            app.BeyazesyaSipariiLabel = uilabel(app.UIFigure);
            app.BeyazesyaSipariiLabel.Text = 'Beyaz esya Siparişi';
            app.KriterleriSeinizLabel = uilabel(app.UIFigure);
            app.KriterleriSeinizLabel.Text = 'Kriterleri Seçiniz';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Siparişiptal', 'Siparişet'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Siparişiptal';
            app.BeyazEyanzSeinizLabel = uilabel(app.UIFigure);
            app.BeyazEyanzSeinizLabel.Text = 'Beyaz Eşyanızı Seçiniz';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'id'; 'Garanti Süresi'; 'Enerji Verimliliği'; 'Ses Seviyesi'; 'Enerji Sınıfı'; 'Fiyat'; 'Beyaz esya'};
            app.UITable.RowName = {};
            app.Switch_2Label = uilabel(app.UIFigure);
            app.Switch_2Label.Text = '';
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.Items = {'Siparişlerisil', 'Siparişigörüntüle'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Siparişlerisil';
            app.BeyazesyayaidgrearamayapEditFieldLabel = uilabel(app.UIFigure);
            app.BeyazesyayaidgrearamayapEditFieldLabel.Text = 'Beyaz esyaya (id)göre arama yap';
            app.BeyazesyayaidgrearamayapEditField = uieditfield(app.UIFigure, 'text');
            app.FiyatButtonGroup = uibuttongroup(app.UIFigure);
            app.FiyatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiyatButtonGroupSelectionChanged, true);
            app.FiyatButtonGroup.Title = 'Fiyat ';
            app.Button_6 = uiradiobutton(app.FiyatButtonGroup);
            app.Button_6.Text = '15346';
            app.Button_6.Value = true;
            app.Button_7 = uiradiobutton(app.FiyatButtonGroup);
            app.Button_7.Text = '24567';
            app.Button_8 = uiradiobutton(app.FiyatButtonGroup);
            app.Button_8.Text = '40342';
            app.Switch_3Label = uilabel(app.UIFigure);
            app.Switch_3Label.Text = 'Switch';
            app.Switch_3 = uiswitch(app.UIFigure, 'slider');
            app.Switch_3.Items = {'AramaYapma', 'AramaYap'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = 'AramaYapma';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlapProjesi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end