classdef gecmis_siparisler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        uekildeListelePanel_3         matlab.ui.container.Panel
        KurutmamakinesiCheckBox       matlab.ui.control.CheckBox
        amarmakinesiCheckBox          matlab.ui.control.CheckBox
        DerindondurucuCheckBox        matlab.ui.control.CheckBox
        BuzdolabCheckBox              matlab.ui.control.CheckBox
        uekildeListelePanel_2         matlab.ui.container.Panel
        SesSeviyesiCheckBox           matlab.ui.control.CheckBox
        EnerjiverimliliiCheckBox      matlab.ui.control.CheckBox
        uekildeListelePanel           matlab.ui.container.Panel
        GarantiSresiCheckBox          matlab.ui.control.CheckBox
        fiyatCheckBox                 matlab.ui.control.CheckBox
        GecmisSiparislerGrntleButton  matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GecmisSiparislerGrntleButton
        function GecmisSiparislerGrntleButtonPushed(app, event)
            t = readtable("Kitap1.xlsx", "Sheet", "Sayfa1");
            
            tablo = struct('Garanti', {}, 'Enerji', {}, 'Ses', {}, 'Sinif', {}, 'Fiyat', {}, 'Beyazeysa', {});   
            for i = 1:size(t, 1)
                tablo(i).Garanti = t.Garanti(i); 
                tablo(i).Enerji = t.Enerji(i); 
                tablo(i).Ses = t.Ses(i); 
                tablo(i).Sinif = t.Sinif{i}; 
                tablo(i).Fiyat = t.Fiyat(i); 
                tablo(i).Beyazeysa = t.Beyazeysa{i};   
            end
            
           if app.fiyatCheckBox.Value
                fiyatFiltre = t.Fiyat > 23458;
                filtrelenmisVeri = t(fiyatFiltre, :);
                app.UITable.Data = filtrelenmisVeri;
                disp(filtrelenmisVeri)
            elseif app.GarantiSresiCheckBox.Value
                garantiFiltre = t.Garanti==36;
                filtrelenmisVeri = t(garantiFiltre, :);
                app.UITable.Data = filtrelenmisVeri;
                disp(filtrelenmisVeri)
             elseif app.SesSeviyesiCheckBox.Value
                sesFiltre = t.Enerji>30;
                filtrelenmisVeri = t(sesFiltre, :);
                app.UITable.Data = filtrelenmisVeri;
                disp(filtrelenmisVeri)
             elseif app.BuzdolabCheckBox.Value
               beyazesyaFiltre = strcmp(t.Beyazeysa, 'Buzdolabı');
                filtrelenmisVeri = t(beyazesyaFiltre, :);
                app.UITable.Data = filtrelenmisVeri;
                disp(filtrelenmisVeri)   
             elseif app.DerindondurucuCheckBox.Value
               beyazesyaFiltre = strcmp(t.Beyazeysa, 'Derin dondurucu');
                filtrelenmisVeri = t(beyazesyaFiltre, :);
                app.UITable.Data = filtrelenmisVeri;
                disp(filtrelenmisVeri)
             elseif app.amarmakinesiCheckBox.Value
               beyazesyaFiltre = strcmp(t.Beyazeysa, 'Çamaşır makinesi');
                filtrelenmisVeri = t(beyazesyaFiltre, :);
                app.UITable.Data = filtrelenmisVeri;
                disp(filtrelenmisVeri)
              elseif app.KurutmamakinesiCheckBox.Value
               beyazesyaFiltre = strcmp(t.Beyazeysa, 'Kurutma makinesi');
                filtrelenmisVeri = t(beyazesyaFiltre, :);
                app.UITable.Data = filtrelenmisVeri;
                disp(filtrelenmisVeri)
              
           else    

                app.UITable.Data = struct2table(tablo);
            end
 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'gecmiss.jpg');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Garanti Süresi'; 'Enerji Verimliliği'; 'Ses seviyesi'; 'Enerji Sinifi'; 'Fiyat'; 'Beyazeysa'};
            app.UITable.RowName = {};
            app.GecmisSiparislerGrntleButton = uibutton(app.UIFigure, 'push');
            app.GecmisSiparislerGrntleButton.ButtonPushedFcn = createCallbackFcn(app, @GecmisSiparislerGrntleButtonPushed, true);
            app.GecmisSiparislerGrntleButton.Text = 'Gecmis Siparislerİ Görüntüle';
            app.uekildeListelePanel = uipanel(app.UIFigure);
            app.uekildeListelePanel.Title = 'Şu Şekilde Listele';
            app.fiyatCheckBox = uicheckbox(app.uekildeListelePanel);
            app.fiyatCheckBox.Text = 'fiyat';
            app.GarantiSresiCheckBox = uicheckbox(app.uekildeListelePanel);
            app.GarantiSresiCheckBox.Text = 'Garanti Süresi';
            app.uekildeListelePanel_2 = uipanel(app.UIFigure);
            app.uekildeListelePanel_2.Title = 'Şu Şekilde Listele';
            app.EnerjiverimliliiCheckBox = uicheckbox(app.uekildeListelePanel_2);
            app.EnerjiverimliliiCheckBox.Text = 'Enerji verimliliği';
            app.SesSeviyesiCheckBox = uicheckbox(app.uekildeListelePanel_2);
            app.SesSeviyesiCheckBox.Text = 'Ses Seviyesi';
            app.uekildeListelePanel_3 = uipanel(app.UIFigure);
            app.uekildeListelePanel_3.Title = 'Şu Şekilde Listele';
            app.BuzdolabCheckBox = uicheckbox(app.uekildeListelePanel_3);
            app.BuzdolabCheckBox.Text = 'Buzdolabı';
            app.DerindondurucuCheckBox = uicheckbox(app.uekildeListelePanel_3);
            app.DerindondurucuCheckBox.Text = 'Derin dondurucu';
            app.amarmakinesiCheckBox = uicheckbox(app.uekildeListelePanel_3);
            app.amarmakinesiCheckBox.Text = 'Çamaşır makinesi';
            app.KurutmamakinesiCheckBox = uicheckbox(app.uekildeListelePanel_3);
            app.KurutmamakinesiCheckBox.Text = 'Kurutma makinesi';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gecmis_siparisler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end